classdef Budget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BudgetUIFigure             matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        IncomeTab                  matlab.ui.container.Tab
        IncomeGrid                 matlab.ui.container.GridLayout
        IncomePiePanel             matlab.ui.container.Panel
        IncomePieGrid              matlab.ui.container.GridLayout
        IncomePie                  matlab.ui.control.UIAxes
        SummaryPanel               matlab.ui.container.Panel
        SummaryGrid                matlab.ui.container.GridLayout
        TakeHome                   matlab.ui.control.NumericEditField
        GrossLabel_5               matlab.ui.control.Label
        Enrollments                matlab.ui.control.NumericEditField
        GrossLabel_4               matlab.ui.control.Label
        Taxes                      matlab.ui.control.NumericEditField
        GrossLabel_3               matlab.ui.control.Label
        Retirement                 matlab.ui.control.NumericEditField
        GrossLabel_2               matlab.ui.control.Label
        Gross                      matlab.ui.control.NumericEditField
        GrossLabel                 matlab.ui.control.Label
        ImputsPanel                matlab.ui.container.Panel
        ImputsGrid                 matlab.ui.container.GridLayout
        ImputVision                matlab.ui.control.NumericEditField
        VisionEditField_3Label     matlab.ui.control.Label
        ImputMedical               matlab.ui.control.NumericEditField
        MedicalEditField_3Label    matlab.ui.control.Label
        ImputDental                matlab.ui.control.NumericEditField
        DentalEditField_3Label     matlab.ui.control.Label
        TaxRatesPanel              matlab.ui.container.Panel
        TaxRatesGrid               matlab.ui.container.GridLayout
        Medicare                   matlab.ui.control.NumericEditField
        MedicareLabel              matlab.ui.control.Label
        SS                         matlab.ui.control.NumericEditField
        SSLabel                    matlab.ui.control.Label
        State                      matlab.ui.control.NumericEditField
        StateLabel                 matlab.ui.control.Label
        Federal                    matlab.ui.control.NumericEditField
        FederalLabel               matlab.ui.control.Label
        ContributionsPanel         matlab.ui.container.Panel
        ContributionsGrid          matlab.ui.container.GridLayout
        HSA                        matlab.ui.control.NumericEditField
        HSAyrEditFieldLabel        matlab.ui.control.Label
        Salary                     matlab.ui.control.NumericEditField
        SalarykEditFieldLabel      matlab.ui.control.Label
        Trad401k                   matlab.ui.control.NumericEditField
        Trad401kEditFieldLabel     matlab.ui.control.Label
        ESPP                       matlab.ui.control.NumericEditField
        ESPPEditFieldLabel         matlab.ui.control.Label
        Roth401k                   matlab.ui.control.NumericEditField
        Roth401kLabel              matlab.ui.control.Label
        EnrollmentsPanel           matlab.ui.container.Panel
        EnrollmentsGrid            matlab.ui.container.GridLayout
        HSA_Enrollment             matlab.ui.control.NumericEditField
        HSALabel                   matlab.ui.control.Label
        Vision                     matlab.ui.control.NumericEditField
        VisionEditFieldLabel       matlab.ui.control.Label
        Dental                     matlab.ui.control.NumericEditField
        DentalEditFieldLabel       matlab.ui.control.Label
        Medical                    matlab.ui.control.NumericEditField
        MedicalEditFieldLabel      matlab.ui.control.Label
        MLI                        matlab.ui.control.NumericEditField
        MLIEditFieldLabel          matlab.ui.control.Label
        FLI                        matlab.ui.control.NumericEditField
        FLIEditFieldLabel          matlab.ui.control.Label
        ADD                        matlab.ui.control.NumericEditField
        ADDEditFieldLabel          matlab.ui.control.Label
        ExpensesTab                matlab.ui.container.Tab
        ExpensesGrid               matlab.ui.container.GridLayout
        UtilitiesmonthPanel        matlab.ui.container.Panel
        UtilitiesGrid              matlab.ui.container.GridLayout
        Trash                      matlab.ui.control.NumericEditField
        SSLabel_5                  matlab.ui.control.Label
        Gas                        matlab.ui.control.NumericEditField
        SSLabel_7                  matlab.ui.control.Label
        Internet                   matlab.ui.control.NumericEditField
        SSLabel_6                  matlab.ui.control.Label
        WaterSewage                matlab.ui.control.NumericEditField
        StateLabel_4               matlab.ui.control.Label
        Electric                   matlab.ui.control.NumericEditField
        FederalLabel_4             matlab.ui.control.Label
        FoodmonthPanel             matlab.ui.container.Panel
        FoodGrid                   matlab.ui.container.GridLayout
        DineIn                     matlab.ui.control.NumericEditField
        SSLabel_3                  matlab.ui.control.Label
        TakeOut                    matlab.ui.control.NumericEditField
        StateLabel_3               matlab.ui.control.Label
        Groceries                  matlab.ui.control.NumericEditField
        FederalLabel_3             matlab.ui.control.Label
        ExpensesPiePanel           matlab.ui.container.Panel
        ExpensesPieGrid            matlab.ui.container.GridLayout
        ExpensesPie                matlab.ui.control.UIAxes
        ExpenseSummaryPanel        matlab.ui.container.Panel
        ExpenseSummaryGrid         matlab.ui.container.GridLayout
        ExpenseOther               matlab.ui.control.NumericEditField
        GrossLabel_12              matlab.ui.control.Label
        ExpenseFood                matlab.ui.control.NumericEditField
        GrossLabel_11              matlab.ui.control.Label
        ExpenseSavings             matlab.ui.control.NumericEditField
        GrossLabel_10              matlab.ui.control.Label
        ExpenseAuto                matlab.ui.control.NumericEditField
        GrossLabel_9               matlab.ui.control.Label
        ExpenseUtilities           matlab.ui.control.NumericEditField
        GrossLabel_8               matlab.ui.control.Label
        ExpenseHome                matlab.ui.control.NumericEditField
        GrossLabel_7               matlab.ui.control.Label
        ExpenseTakeHome            matlab.ui.control.NumericEditField
        GrossLabel_6               matlab.ui.control.Label
        OthermonthPanel            matlab.ui.container.Panel
        OtherGrid                  matlab.ui.container.GridLayout
        Misc_2                     matlab.ui.control.NumericEditField
        MedicalEditField_3Label_5  matlab.ui.control.Label
        Misc                       matlab.ui.control.NumericEditField
        MedicalEditField_3Label_4  matlab.ui.control.Label
        Gym                        matlab.ui.control.NumericEditField
        MedicalEditField_3Label_3  matlab.ui.control.Label
        Dogs                       matlab.ui.control.NumericEditField
        VisionEditField_3Label_2   matlab.ui.control.Label
        Vacation                   matlab.ui.control.NumericEditField
        DentalEditField_3Label_2   matlab.ui.control.Label
        AutomonthPanel             matlab.ui.container.Panel
        AutoGrid                   matlab.ui.container.GridLayout
        AutoExcise                 matlab.ui.control.NumericEditField
        SSLabel_4                  matlab.ui.control.Label
        AutoMaintenance            matlab.ui.control.NumericEditField
        SSLabel_2                  matlab.ui.control.Label
        AutoInsurance              matlab.ui.control.NumericEditField
        StateLabel_2               matlab.ui.control.Label
        AutoPayment                matlab.ui.control.NumericEditField
        FederalLabel_2             matlab.ui.control.Label
        HomePanel                  matlab.ui.container.Panel
        HomeGrid                   matlab.ui.container.GridLayout
        HomeInsurance              matlab.ui.control.NumericEditField
        Roth401kLabel_6            matlab.ui.control.Label
        Projects                   matlab.ui.control.NumericEditField
        Roth401kLabel_5            matlab.ui.control.Label
        Escrow                     matlab.ui.control.NumericEditField
        Roth401kLabel_4            matlab.ui.control.Label
        Repairs                    matlab.ui.control.NumericEditField
        Roth401kLabel_3            matlab.ui.control.Label
        PandI                      matlab.ui.control.NumericEditField
        MortgageTotalLabel         matlab.ui.control.Label
        HistoryTab                 matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        HistoryTabGroup            matlab.ui.container.TabGroup
        ElectricTab                matlab.ui.container.Tab
        BankGrid_2                 matlab.ui.container.GridLayout
        BalanceLabel               matlab.ui.control.Label
        EnergyUsageLabel           matlab.ui.control.Label
        DeliveryChargeLabel        matlab.ui.control.Label
        SupplyChargeLabel          matlab.ui.control.Label
        ElectricSupply             matlab.ui.control.Label
        ElectricDelivery           matlab.ui.control.Label
        ElectricUsage              matlab.ui.control.Label
        ElectricBalance            matlab.ui.control.Label
        ElectricPlot               matlab.ui.control.UIAxes
        GasTab                     matlab.ui.container.Tab
        BankTab                    matlab.ui.container.Tab
        BankGrid                   matlab.ui.container.GridLayout
        BankDelta                  matlab.ui.control.Label
        DeltaLabel                 matlab.ui.control.Label
        BeginningBalanceLabel      matlab.ui.control.Label
        DepositsAdditionsLabel     matlab.ui.control.Label
        ATMDebitSubLabel           matlab.ui.control.Label
        OtherSubtractionsLabel     matlab.ui.control.Label
        ChecksLabel                matlab.ui.control.Label
        ServiceFeesLabel           matlab.ui.control.Label
        EndingBalanceLabel         matlab.ui.control.Label
        BankEnding                 matlab.ui.control.Label
        BankService                matlab.ui.control.Label
        BankChecks                 matlab.ui.control.Label
        BankOther                  matlab.ui.control.Label
        BankATM                    matlab.ui.control.Label
        BankDep                    matlab.ui.control.Label
        BankBeg                    matlab.ui.control.Label
        BankPlot                   matlab.ui.control.UIAxes
        ETradeTab                  matlab.ui.container.Tab
        BankTab_2                  matlab.ui.container.Tab
        BankGrid_3                 matlab.ui.container.GridLayout
        BankDelta_2                matlab.ui.control.Label
        NetChangeLabel             matlab.ui.control.Label
        BeginningBalanceLabel_2    matlab.ui.control.Label
        EndingBalanceLabel_2       matlab.ui.control.Label
        BankDep_2                  matlab.ui.control.Label
        BankBeg_2                  matlab.ui.control.Label
        BankPlot_2                 matlab.ui.control.UIAxes
        FidelityTab                matlab.ui.container.Tab
        CapitalOneTab              matlab.ui.container.Tab
        HSATab                     matlab.ui.container.Tab
        PDF                        matlab.ui.control.Hyperlink
        Type                       matlab.ui.control.DropDown
        TypeLabel                  matlab.ui.control.Label
        Month                      matlab.ui.control.DropDown
        MonthDropDownLabel         matlab.ui.control.Label
        Year                       matlab.ui.control.DropDown
        YearDropDownLabel          matlab.ui.control.Label
        ProjectTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        HistoryPath = 'C:\Users\nkimmitt\Desktop\Records';
        BankPoint %singular point on Bank Plot graph
    end
    
    methods (Access = private)
        
        function UpdateIncome(app)
            %Calculate Gross Pay
            imputted_biweekly = app.ImputDental.Value+app.ImputMedical.Value+app.ImputVision.Value;
            imputted_yearly = imputted_biweekly*26;
            gross_pay = app.Salary.Value*1000+imputted_yearly;
            biweekly_gross = gross_pay/26;
            app.HSA_Enrollment.Value = app.HSA.Value/26;
            
            biweekly_salary = app.Salary.Value*1000/26;

            %Benfits and Pre-Tax Amount
            traditional_401k = biweekly_salary*app.Trad401k.Value/100;
            roth_ira = biweekly_salary*app.Roth401k.Value/100;
            benefits = app.Medical.Value+app.Dental.Value+app.Vision.Value+app.HSA.Value/26;
            deductions_biweekly = benefits + traditional_401k;
            pre_tax_biweekly = biweekly_gross-deductions_biweekly;

            %Determine Taxes
            social_security_tax = pre_tax_biweekly*app.SS.Value/100;
            medicare_tax = pre_tax_biweekly*app.Medicare.Value/100;
            federal_tax = pre_tax_biweekly*app.Federal.Value/100;
            mass_tax = pre_tax_biweekly*app.State.Value/100;
            tax_total_biweekly = social_security_tax+medicare_tax+federal_tax+mass_tax;

            %Other
            other = app.FLI.Value+app.MLI.Value+app.ADD.Value;

            %Final Take Home Value
            take_home_biweekly = pre_tax_biweekly-tax_total_biweekly-roth_ira-app.ESPP.Value/100*biweekly_salary-other-imputted_biweekly;

            %Summary Panel
            app.Gross.Value = biweekly_gross;
            app.Retirement.Value = traditional_401k+roth_ira;
            app.Taxes.Value = tax_total_biweekly;
            app.Enrollments.Value = other+benefits+app.ESPP.Value/100*biweekly_salary;
            app.TakeHome.Value = take_home_biweekly;

            %Pie Chart
            factor = 1000/biweekly_salary;
            %Column 1: Total Take Home Pay
            A = biweekly_salary;
            A = A*factor;
            
            %Column 2: Deductions
            B1 = round(app.TakeHome.Value*factor);
            B3 = round(app.Taxes.Value*factor);
            B4 = round(app.HSA_Enrollment.Value*factor);
            B5 = round(app.Retirement.Value*factor);
            B6 = round(app.ESPP.Value/100*biweekly_salary*factor);
            B7 = round((benefits-app.HSA_Enrollment.Value+other)*factor);

            labels = {['Take Home: (' num2str(B1/10) '%)'],['Taxes: (' num2str(B3/10) '%)'],['HSA: (' num2str(B4/10) '%)'],['Retirement: (' num2str(B5/10) '%)'],['ESPP: (' num2str(B6/10) '%)'],['Benefits: (' num2str(B7/10) '%)']};
            pie(app.IncomePie,[B1 B3 B4 B5 B6 B7],labels);
            colormap(app.IncomePie,'turbo');
            title(app.IncomePie,'Gross Pay Overview');
            
        end
        
        function UpdateExpenses(app)
            app.ExpenseTakeHome.Value = 7500;
            % app.ExpenseTakeHome.Value = app.TakeHome.Value*26/12;
            app.ExpenseHome.Value = (app.PandI.Value + app.Projects.Value+app.Escrow.Value+app.Repairs.Value+app.HomeInsurance.Value);
            app.ExpenseUtilities.Value = (app.Electric.Value+app.WaterSewage.Value+app.Internet.Value+app.Gas.Value+app.Trash.Value);
            app.ExpenseAuto.Value = app.AutoPayment.Value+app.AutoExcise.Value+app.AutoInsurance.Value+app.AutoMaintenance.Value/100;
            app.ExpenseFood.Value = (app.Groceries.Value+app.TakeOut.Value+app.DineIn.Value);
            app.ExpenseOther.Value = (app.Dogs.Value+app.Misc.Value+app.Vacation.Value+app.Gym.Value);
            app.ExpenseSavings.Value = app.ExpenseTakeHome.Value-app.ExpenseHome.Value-app.ExpenseUtilities.Value-app.ExpenseAuto.Value-app.ExpenseFood.Value-app.ExpenseOther.Value;
            
            B1= round(app.ExpenseHome.Value/app.ExpenseTakeHome.Value*100);
            B2 = round(app.ExpenseUtilities.Value/app.ExpenseTakeHome.Value*100);
            B3= round(app.ExpenseAuto.Value/app.ExpenseTakeHome.Value*100);
            B4= round(app.ExpenseFood.Value/app.ExpenseTakeHome.Value*100);
            B5 = round(app.ExpenseOther.Value/app.ExpenseTakeHome.Value*100);
            B6 = round(app.ExpenseSavings.Value/app.ExpenseTakeHome.Value*100);
            formatSpec = '%.1f';
            labels = {['Home: (' num2str(B1,formatSpec) '%)'],['Utilities: (' num2str(B2,formatSpec) '%)'],['Auto: (' num2str(B3,formatSpec) '%)'],['Food: (' num2str(B4,formatSpec) '%)'],['Other: (' num2str(B5,formatSpec) '%)'],['Savings: (' num2str(B6,formatSpec) '%)']};
            pie(app.ExpensesPie,[B1 B2 B3 B4 B5 B6],labels);
            colormap(app.ExpensesPie,'turbo')
            title(app.ExpensesPie,'Expenses Overview')
        end
        
        function UpdateAll(app)
            UpdateIncome(app);
            UpdateExpenses(app);
        end
        
        function balance = ReadBank(app,filename,name)
            try
                p = pdfRead(filename);
                r = p{1};
                
                begin = strfind(r,'Beginning balance on ');
    %             ends = strfind(r,'Please see the Important ')
                
                r = p{1}(begin:end);
                r = erase(r,',');
                r = erase(r,'.');
                
                B = str2double(regexp(r,'\d*','Match'))/100;
                
                B = B([3 4 5 6 7 8 11]);
                %1,2 are dates, 9 10 are dates, remove them
    
                %Output
                %B(1) = Beginning Balance
                %B(2) = Deposits / Additions
                %B(3) = ATM/debit subs
                %B(4) = Other Subtractions
                %B(5) = Checks
                %B(6) = Service Fees
                %B(7) = Ending Balance
                app.BankBeg.Text = ['$' num2str(B(1))];
                app.BankDep.Text = ['+$' num2str(B(2))];
                app.BankATM.Text = ['-$' num2str(B(3))];
                app.BankOther.Text = ['-$' num2str(B(4))];
                app.BankChecks.Text = ['-$' num2str(B(5))];
                app.BankService.Text = ['-$' num2str(B(6))];
                app.BankEnding.Text = ['$' num2str(B(7))];
                
                delta = B(7)-B(1);
                balance = B(7);

                %Add Point to Graph
                temp = strsplit(name(5:end-4),'-'); %removes BoA/.pdf
                date = datetime(str2double(temp{1}),str2double(temp{2}),1);
                if ~isempty(app.BankPoint)
                    delete(app.BankPoint);
                end

                hold(app.BankPlot,'on');
                app.BankPoint = plot(app.BankPlot,date,balance/1000,'rp','MarkerFaceColor',[1 0.6 0.6],'MarkerEdgeColor',[1 0 0],'MarkerSize',12);
                hold(app.BankPlot,'off');

                if delta > 0
                    app.BankDelta.Text = ['+$' num2str(delta)];
                    app.BankDelta.FontColor = [0.02,0.55,0.12];
                else
                    app.BankDelta.Text = ['-$' num2str(abs(delta))];
                    app.BankDelta.FontColor = [1 0 0];
                end
            catch
                uialert(app.BudgetUIFigure,'No Bank Statement','Unknown File','Icon','error');
            end
        end
        
        function PlotBank(app)
            pathname = [app.HistoryPath filesep 'Bank'];
            filelist = dir(fullfile(pathname, '**', '*.pdf'));

            for ii = 1:length(filelist)
                name = filelist(ii).name;
                folder = filelist(ii).folder;
                temp = strsplit(filelist(ii).name(5:end-4),'-'); %removes BoA/.pdf
                date(ii) = datetime(str2double(temp{1}),str2double(temp{2}),1);
                [folder filesep name]
                balance(ii) = ReadBank(app,[folder filesep name],name);
            end

            plot(app.BankPlot,date,balance/1000,'LineWidth',2,'Color','k');
        end
    end
    
    methods (Access = public)
        
        function UpdateHistory(app)
            year = app.Year.Value;
            month = app.Month.Value;
            type = app.Type.Value;

            switch type
                case '1'
                    app.HistoryTabGroup.SelectedTab = app.ElectricTab;
                case '2'
                    app.HistoryTabGroup.SelectedTab = app.GasTab;
                case '3'
                    app.HistoryTabGroup.SelectedTab = app.BankTab;
                    name = ['BoA-' year '-' month '.pdf'];
                    filename = [app.HistoryPath filesep 'Bank' filesep year filesep name];
                    ReadBank(app,filename,name);

                case '4'
                    app.HistoryTabGroup.SelectedTab = app.ETradeTab;
                case '5'
                    app.HistoryTabGroup.SelectedTab = app.FidelityTab;
                case '6'
                    app.HistoryTabGroup.SelectedTab = app.CapitalOneTab;
                case '7'
                    app.HistoryTabGroup.SelectedTab = app.HSATab;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            UpdateAll(app);
            % PlotBank(app);
            % UpdateHistory(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            UpdateAll(app);
        end

        % Value changed function: Salary
        function SalaryValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: Roth401k
        function Roth401kValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: Trad401k
        function Trad401kValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: HSA
        function HSAValueChanged(app, event)
            UpdateAll(app)            
        end

        % Value changed function: ESPP
        function ESPPValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: Federal
        function FederalValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: State
        function StateValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: SS
        function SSValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: Medicare
        function MedicareValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: ADD
        function ADDValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: FLI
        function FLIValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: MLI
        function MLIValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: Medical
        function MedicalValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: Dental
        function DentalValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: Vision
        function VisionValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: HSA_Enrollment
        function HSA_EnrollmentValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: ImputDental
        function ImputDentalValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: ImputMedical
        function ImputMedicalValueChanged(app, event)
            UpdateAll(app)
        end

        % Value changed function: ImputVision
        function ImputVisionValueChanged(app, event)
            UpdateAll(app)
        end

        % Callback function
        function ButtonPushed2(app, event)
            UpdateExpenses(app);
        end

        % Value changed function: PandI
        function PandIValueChanged(app, event)
            UpdateAll(app);
        end

        % Callback function
        function HomeInsuranceValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Escrow
        function EscrowValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Repairs
        function RepairsValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Electric
        function ElectricValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Gas
        function GasValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: WaterSewage
        function WaterSewageValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Trash
        function TrashValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Internet
        function InternetValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: AutoPayment
        function AutoPaymentValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: AutoInsurance
        function AutoInsuranceValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: AutoMaintenance
        function AutoMaintenanceValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: AutoExcise
        function AutoExciseValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Groceries
        function GroceriesValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: TakeOut
        function TakeOutValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: DineIn
        function DineInValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Dogs
        function DogsValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Vacation
        function VacationValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: Misc
        function MiscValueChanged(app, event)
            UpdateAll(app);
        end

        % Callback function
        function IncomeRatioValueChanged(app, event)
            UpdateAll(app);
        end

        % Callback function: not associated with a component
        function IncomeRatioValueChanged2(app, event)
            UpdateAll(app);
        end

        % Callback function: not associated with a component
        function IncomeRatioValueChanging(app, event)
            UpdateAll(app);
        end

        % Callback function: PDF
        function PDFHyperlinkClicked(app, event)
            year = app.Year.Value;
            month = app.Month.Value;
            type = app.Type.Value;
            switch type
                case '1' %electric
                    try
                        name = ['Electric-' year '-' month '.pdf'];
                        fullpath = [app.HistoryPath filesep 'Utilities' filesep year filesep name];
                        winopen(fullpath);
                    catch
                        uialert(app.BudgetUIFigure,'No Electric Bill Exists','Unknown File','Icon','error');
                    end
                case '2' %Gas
                    try
                        name = ['Gas-' year '-' month '.pdf'];
                        fullpath = [app.HistoryPath filesep 'Utilities' filesep year filesep name];
                        winopen(fullpath);
                    catch
                        uialert(app.BudgetUIFigure,'No Gas Bill Exists','Unknown File','Icon','error');
                    end
                case '3' %Bank
                    try
                        name = ['BoA-' year '-' month '.pdf'];
                        fullpath = [app.HistoryPath filesep 'Bank' filesep year filesep name];
                        winopen(fullpath);
                    catch
                        uialert(app.BudgetUIFigure,'No Bank Statement Exists','Unknown File','Icon','error');
                    end
                case '4' %ETrade
                    try
                        name = ['ETrade-' year '-' month '.pdf'];
                        fullpath = [app.HistoryPath filesep 'ETrade' filesep year filesep name];
                        winopen(fullpath);
                    catch
                        uialert(app.BudgetUIFigure,'No Account Statement Exists','Unknown File','Icon','error');
                    end
                case '5' %Fidelity
                    try
                        name = ['Fidelity-' year '-' month '.pdf'];
                        fullpath = [app.HistoryPath filesep 'Fidelity' filesep year filesep name];
                        winopen(fullpath);
                    catch
                        uialert(app.BudgetUIFigure,'No Account Statement Exists','Unknown File','Icon','error');
                    end
                case '6' %Capital One
                    try
                        name = ['Capital-' year '-' month '.pdf'];
                        fullpath = [app.HistoryPath filesep 'Capital One' filesep year filesep name];
                        winopen(fullpath);
                    catch
                        uialert(app.BudgetUIFigure,'No Statement Exists','Unknown File','Icon','error');
                    end
                case '7' %HSA
                    try
                        name = ['HSA-' year '-' month '.pdf'];
                        fullpath = [app.HistoryPath filesep 'HSA' filesep year filesep name];
                        winopen(fullpath);
                    catch
                        uialert(app.BudgetUIFigure,'No Statement Exists','Unknown File','Icon','error');
                    end
            end
        end

        % Value changed function: Year
        function YearValueChanged(app, event)
            UpdateHistory(app);
            
        end

        % Value changed function: Month
        function MonthValueChanged(app, event)
            UpdateHistory(app);
        end

        % Value changed function: Type
        function TypeValueChanged(app, event)
            UpdateHistory(app);
        end

        % Callback function
        function ButtonPushed3(app, event)
            PlotBank(app);

            
        end

        % Value changed function: Projects
        function ProjectsValueChanged(app, event)
            UpdateAll(app);
        end

        % Value changed function: ExpenseTakeHome
        function ExpenseTakeHomeValueChanged(app, event)
            value = app.ExpenseTakeHome.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BudgetUIFigure = uifigure('Visible', 'off');
            app.BudgetUIFigure.Name = '2023 Budget';
            app.TabGroup = uitabgroup(app.BudgetUIFigure);
            app.IncomeTab = uitab(app.TabGroup);
            app.IncomeTab.Title = 'Income';
            app.IncomeGrid = uigridlayout(app.IncomeTab);
            app.EnrollmentsPanel = uipanel(app.IncomeGrid);
            app.EnrollmentsPanel.Title = 'Enrollments ($/Biweekly)';
            app.EnrollmentsGrid = uigridlayout(app.EnrollmentsPanel);
            app.ADDEditFieldLabel = uilabel(app.EnrollmentsGrid);
            app.ADDEditFieldLabel.Text = 'AD&D';
            app.ADD = uieditfield(app.EnrollmentsGrid, 'numeric');
            app.ADD.ValueChangedFcn = createCallbackFcn(app, @ADDValueChanged, true);
            app.ADD.Value = 0.18;
            app.FLIEditFieldLabel = uilabel(app.EnrollmentsGrid);
            app.FLIEditFieldLabel.Text = 'FLI';
            app.FLI = uieditfield(app.EnrollmentsGrid, 'numeric');
            app.FLI.ValueChangedFcn = createCallbackFcn(app, @FLIValueChanged, true);
            app.FLI.Value = 4.55;
            app.MLIEditFieldLabel = uilabel(app.EnrollmentsGrid);
            app.MLIEditFieldLabel.Text = 'MLI';
            app.MLI = uieditfield(app.EnrollmentsGrid, 'numeric');
            app.MLI.ValueChangedFcn = createCallbackFcn(app, @MLIValueChanged, true);
            app.MLI.Value = 8.19;
            app.MedicalEditFieldLabel = uilabel(app.EnrollmentsGrid);
            app.MedicalEditFieldLabel.Text = 'Medical';
            app.Medical = uieditfield(app.EnrollmentsGrid, 'numeric');
            app.Medical.ValueChangedFcn = createCallbackFcn(app, @MedicalValueChanged, true);
            app.Medical.Value = 88.18;
            app.DentalEditFieldLabel = uilabel(app.EnrollmentsGrid);
            app.DentalEditFieldLabel.Text = 'Dental';
            app.Dental = uieditfield(app.EnrollmentsGrid, 'numeric');
            app.Dental.ValueChangedFcn = createCallbackFcn(app, @DentalValueChanged, true);
            app.Dental.Value = 6.72;
            app.VisionEditFieldLabel = uilabel(app.EnrollmentsGrid);
            app.VisionEditFieldLabel.Text = 'Vision';
            app.Vision = uieditfield(app.EnrollmentsGrid, 'numeric');
            app.Vision.ValueChangedFcn = createCallbackFcn(app, @VisionValueChanged, true);
            app.Vision.Value = 3.33;
            app.HSALabel = uilabel(app.EnrollmentsGrid);
            app.HSALabel.Text = 'HSA ';
            app.HSA_Enrollment = uieditfield(app.EnrollmentsGrid, 'numeric');
            app.HSA_Enrollment.ValueChangedFcn = createCallbackFcn(app, @HSA_EnrollmentValueChanged, true);
            app.ContributionsPanel = uipanel(app.IncomeGrid);
            app.ContributionsPanel.Title = 'Contributions';
            app.ContributionsGrid = uigridlayout(app.ContributionsPanel);
            app.Roth401kLabel = uilabel(app.ContributionsGrid);
            app.Roth401kLabel.Text = 'Roth 401k (%)';
            app.Roth401k = uieditfield(app.ContributionsGrid, 'numeric');
            app.Roth401k.ValueChangedFcn = createCallbackFcn(app, @Roth401kValueChanged, true);
            app.Roth401k.Value = 10;
            app.ESPPEditFieldLabel = uilabel(app.ContributionsGrid);
            app.ESPPEditFieldLabel.Text = 'ESPP (%)';
            app.ESPP = uieditfield(app.ContributionsGrid, 'numeric');
            app.ESPP.ValueChangedFcn = createCallbackFcn(app, @ESPPValueChanged, true);
            app.ESPP.Value = 10;
            app.Trad401kEditFieldLabel = uilabel(app.ContributionsGrid);
            app.Trad401kEditFieldLabel.Text = 'Trad 401k (%)';
            app.Trad401k = uieditfield(app.ContributionsGrid, 'numeric');
            app.Trad401k.ValueChangedFcn = createCallbackFcn(app, @Trad401kValueChanged, true);
            app.SalarykEditFieldLabel = uilabel(app.ContributionsGrid);
            app.SalarykEditFieldLabel.Text = 'Salary (k$)';
            app.Salary = uieditfield(app.ContributionsGrid, 'numeric');
            app.Salary.ValueChangedFcn = createCallbackFcn(app, @SalaryValueChanged, true);
            app.Salary.Value = 107.5;
            app.HSAyrEditFieldLabel = uilabel(app.ContributionsGrid);
            app.HSAyrEditFieldLabel.Text = 'HSA ($/yr)';
            app.HSA = uieditfield(app.ContributionsGrid, 'numeric');
            app.HSA.ValueChangedFcn = createCallbackFcn(app, @HSAValueChanged, true);
            app.TaxRatesPanel = uipanel(app.IncomeGrid);
            app.TaxRatesPanel.Title = 'Tax Rates (%, 2023)';
            app.TaxRatesGrid = uigridlayout(app.TaxRatesPanel);
            app.FederalLabel = uilabel(app.TaxRatesGrid);
            app.FederalLabel.Text = 'Federal';
            app.Federal = uieditfield(app.TaxRatesGrid, 'numeric');
            app.Federal.ValueChangedFcn = createCallbackFcn(app, @FederalValueChanged, true);
            app.Federal.Value = 14.25;
            app.StateLabel = uilabel(app.TaxRatesGrid);
            app.StateLabel.Text = 'State';
            app.State = uieditfield(app.TaxRatesGrid, 'numeric');
            app.State.ValueChangedFcn = createCallbackFcn(app, @StateValueChanged, true);
            app.State.Value = 4.75;
            app.SSLabel = uilabel(app.TaxRatesGrid);
            app.SSLabel.Text = 'Social';
            app.SS = uieditfield(app.TaxRatesGrid, 'numeric');
            app.SS.ValueChangedFcn = createCallbackFcn(app, @SSValueChanged, true);
            app.SS.Value = 6.4;
            app.MedicareLabel = uilabel(app.TaxRatesGrid);
            app.MedicareLabel.Text = 'Medicare';
            app.Medicare = uieditfield(app.TaxRatesGrid, 'numeric');
            app.Medicare.ValueChangedFcn = createCallbackFcn(app, @MedicareValueChanged, true);
            app.Medicare.Value = 1.5;
            app.ImputsPanel = uipanel(app.IncomeGrid);
            app.ImputsPanel.Title = 'Imputs ($/Biweekly)';
            app.ImputsGrid = uigridlayout(app.ImputsPanel);
            app.DentalEditField_3Label = uilabel(app.ImputsGrid);
            app.DentalEditField_3Label.Text = 'Dental';
            app.ImputDental = uieditfield(app.ImputsGrid, 'numeric');
            app.ImputDental.ValueChangedFcn = createCallbackFcn(app, @ImputDentalValueChanged, true);
            app.ImputDental.Value = 8.73;
            app.MedicalEditField_3Label = uilabel(app.ImputsGrid);
            app.MedicalEditField_3Label.Text = 'Medical';
            app.ImputMedical = uieditfield(app.ImputsGrid, 'numeric');
            app.ImputMedical.ValueChangedFcn = createCallbackFcn(app, @ImputMedicalValueChanged, true);
            app.ImputMedical.Value = 88.16;
            app.VisionEditField_3Label = uilabel(app.ImputsGrid);
            app.VisionEditField_3Label.Text = 'Vision';
            app.ImputVision = uieditfield(app.ImputsGrid, 'numeric');
            app.ImputVision.ValueChangedFcn = createCallbackFcn(app, @ImputVisionValueChanged, true);
            app.ImputVision.Value = 2;
            app.SummaryPanel = uipanel(app.IncomeGrid);
            app.SummaryPanel.Title = 'Summary ($/Biweekly)';
            app.SummaryGrid = uigridlayout(app.SummaryPanel);
            app.GrossLabel = uilabel(app.SummaryGrid);
            app.GrossLabel.Text = 'Gross';
            app.Gross = uieditfield(app.SummaryGrid, 'numeric');
            app.GrossLabel_2 = uilabel(app.SummaryGrid);
            app.GrossLabel_2.Text = 'Retirement';
            app.Retirement = uieditfield(app.SummaryGrid, 'numeric');
            app.GrossLabel_3 = uilabel(app.SummaryGrid);
            app.GrossLabel_3.Text = 'Taxes';
            app.Taxes = uieditfield(app.SummaryGrid, 'numeric');
            app.GrossLabel_4 = uilabel(app.SummaryGrid);
            app.GrossLabel_4.Text = 'Enrollments';
            app.Enrollments = uieditfield(app.SummaryGrid, 'numeric');
            app.GrossLabel_5 = uilabel(app.SummaryGrid);
            app.GrossLabel_5.Text = 'Take Home';
            app.TakeHome = uieditfield(app.SummaryGrid, 'numeric');
            app.IncomePiePanel = uipanel(app.IncomeGrid);
            app.IncomePieGrid = uigridlayout(app.IncomePiePanel);
            app.IncomePie = uiaxes(app.IncomePieGrid);
            title(app.IncomePie, 'Title')
            xlabel(app.IncomePie, 'X')
            ylabel(app.IncomePie, 'Y')
            zlabel(app.IncomePie, 'Z')
            app.ExpensesTab = uitab(app.TabGroup);
            app.ExpensesTab.Title = 'Expenses';
            app.ExpensesGrid = uigridlayout(app.ExpensesTab);
            app.HomePanel = uipanel(app.ExpensesGrid);
            app.HomePanel.Title = 'Home ($/month)';
            app.HomeGrid = uigridlayout(app.HomePanel);
            app.MortgageTotalLabel = uilabel(app.HomeGrid);
            app.MortgageTotalLabel.Text = 'P&I';
            app.PandI = uieditfield(app.HomeGrid, 'numeric');
            app.PandI.ValueChangedFcn = createCallbackFcn(app, @PandIValueChanged, true);
            app.PandI.Value = 2538;
            app.Roth401kLabel_3 = uilabel(app.HomeGrid);
            app.Roth401kLabel_3.Text = 'Repairs';
            app.Repairs = uieditfield(app.HomeGrid, 'numeric');
            app.Repairs.ValueChangedFcn = createCallbackFcn(app, @RepairsValueChanged, true);
            app.Repairs.Value = 130;
            app.Roth401kLabel_4 = uilabel(app.HomeGrid);
            app.Roth401kLabel_4.Text = 'Escrow';
            app.Escrow = uieditfield(app.HomeGrid, 'numeric');
            app.Escrow.ValueChangedFcn = createCallbackFcn(app, @EscrowValueChanged, true);
            app.Escrow.Value = 473;
            app.Roth401kLabel_5 = uilabel(app.HomeGrid);
            app.Roth401kLabel_5.Text = 'Projects';
            app.Projects = uieditfield(app.HomeGrid, 'numeric');
            app.Projects.ValueChangedFcn = createCallbackFcn(app, @ProjectsValueChanged, true);
            app.Projects.Value = 200;
            app.Roth401kLabel_6 = uilabel(app.HomeGrid);
            app.Roth401kLabel_6.Text = 'Insurance';
            app.HomeInsurance = uieditfield(app.HomeGrid, 'numeric');
            app.HomeInsurance.Value = 117;
            app.AutomonthPanel = uipanel(app.ExpensesGrid);
            app.AutomonthPanel.Title = 'Auto ($/month)';
            app.AutoGrid = uigridlayout(app.AutomonthPanel);
            app.FederalLabel_2 = uilabel(app.AutoGrid);
            app.FederalLabel_2.Text = 'Payment';
            app.AutoPayment = uieditfield(app.AutoGrid, 'numeric');
            app.AutoPayment.ValueChangedFcn = createCallbackFcn(app, @AutoPaymentValueChanged, true);
            app.AutoPayment.Value = 411;
            app.StateLabel_2 = uilabel(app.AutoGrid);
            app.StateLabel_2.Text = 'Insurance';
            app.AutoInsurance = uieditfield(app.AutoGrid, 'numeric');
            app.AutoInsurance.ValueChangedFcn = createCallbackFcn(app, @AutoInsuranceValueChanged, true);
            app.AutoInsurance.Value = 310;
            app.SSLabel_2 = uilabel(app.AutoGrid);
            app.SSLabel_2.Text = 'Maintenance';
            app.AutoMaintenance = uieditfield(app.AutoGrid, 'numeric');
            app.AutoMaintenance.ValueChangedFcn = createCallbackFcn(app, @AutoMaintenanceValueChanged, true);
            app.AutoMaintenance.Value = 100;
            app.SSLabel_4 = uilabel(app.AutoGrid);
            app.SSLabel_4.Text = 'Excise';
            app.AutoExcise = uieditfield(app.AutoGrid, 'numeric');
            app.AutoExcise.ValueChangedFcn = createCallbackFcn(app, @AutoExciseValueChanged, true);
            app.AutoExcise.Value = 31;
            app.OthermonthPanel = uipanel(app.ExpensesGrid);
            app.OthermonthPanel.Title = 'Other ($/month)';
            app.OtherGrid = uigridlayout(app.OthermonthPanel);
            app.DentalEditField_3Label_2 = uilabel(app.OtherGrid);
            app.DentalEditField_3Label_2.Text = 'Vacation';
            app.Vacation = uieditfield(app.OtherGrid, 'numeric');
            app.Vacation.ValueChangedFcn = createCallbackFcn(app, @VacationValueChanged, true);
            app.Vacation.Value = 100;
            app.VisionEditField_3Label_2 = uilabel(app.OtherGrid);
            app.VisionEditField_3Label_2.Text = 'Dogs';
            app.Dogs = uieditfield(app.OtherGrid, 'numeric');
            app.Dogs.ValueChangedFcn = createCallbackFcn(app, @DogsValueChanged, true);
            app.Dogs.Value = 250;
            app.MedicalEditField_3Label_3 = uilabel(app.OtherGrid);
            app.MedicalEditField_3Label_3.Text = 'Gym';
            app.Gym = uieditfield(app.OtherGrid, 'numeric');
            app.Gym.Value = 60;
            app.MedicalEditField_3Label_4 = uilabel(app.OtherGrid);
            app.MedicalEditField_3Label_4.Text = 'Misc. Items';
            app.Misc = uieditfield(app.OtherGrid, 'numeric');
            app.Misc.ValueChangedFcn = createCallbackFcn(app, @MiscValueChanged, true);
            app.Misc.Value = 500;
            app.MedicalEditField_3Label_5 = uilabel(app.OtherGrid);
            app.MedicalEditField_3Label_5.Text = 'Piano';
            app.Misc_2 = uieditfield(app.OtherGrid, 'numeric');
            app.Misc_2.Value = 287;
            app.ExpenseSummaryPanel = uipanel(app.ExpensesGrid);
            app.ExpenseSummaryPanel.Title = 'Summary ($/month)';
            app.ExpenseSummaryGrid = uigridlayout(app.ExpenseSummaryPanel);
            app.GrossLabel_6 = uilabel(app.ExpenseSummaryGrid);
            app.GrossLabel_6.Text = 'Take Home';
            app.ExpenseTakeHome = uieditfield(app.ExpenseSummaryGrid, 'numeric');
            app.ExpenseTakeHome.ValueChangedFcn = createCallbackFcn(app, @ExpenseTakeHomeValueChanged, true);
            app.GrossLabel_7 = uilabel(app.ExpenseSummaryGrid);
            app.GrossLabel_7.Text = 'Home';
            app.ExpenseHome = uieditfield(app.ExpenseSummaryGrid, 'numeric');
            app.GrossLabel_8 = uilabel(app.ExpenseSummaryGrid);
            app.GrossLabel_8.Text = 'Utilities';
            app.ExpenseUtilities = uieditfield(app.ExpenseSummaryGrid, 'numeric');
            app.GrossLabel_9 = uilabel(app.ExpenseSummaryGrid);
            app.GrossLabel_9.Text = 'Auto';
            app.ExpenseAuto = uieditfield(app.ExpenseSummaryGrid, 'numeric');
            app.GrossLabel_10 = uilabel(app.ExpenseSummaryGrid);
            app.GrossLabel_10.Text = 'Savings';
            app.ExpenseSavings = uieditfield(app.ExpenseSummaryGrid, 'numeric');
            app.GrossLabel_11 = uilabel(app.ExpenseSummaryGrid);
            app.GrossLabel_11.Text = 'Food';
            app.ExpenseFood = uieditfield(app.ExpenseSummaryGrid, 'numeric');
            app.GrossLabel_12 = uilabel(app.ExpenseSummaryGrid);
            app.GrossLabel_12.Text = 'Other';
            app.ExpenseOther = uieditfield(app.ExpenseSummaryGrid, 'numeric');
            app.ExpensesPiePanel = uipanel(app.ExpensesGrid);
            app.ExpensesPieGrid = uigridlayout(app.ExpensesPiePanel);
            app.ExpensesPie = uiaxes(app.ExpensesPieGrid);
            title(app.ExpensesPie, 'Title')
            xlabel(app.ExpensesPie, 'X')
            ylabel(app.ExpensesPie, 'Y')
            zlabel(app.ExpensesPie, 'Z')
            app.FoodmonthPanel = uipanel(app.ExpensesGrid);
            app.FoodmonthPanel.Title = 'Food ($/month)';
            app.FoodGrid = uigridlayout(app.FoodmonthPanel);
            app.FederalLabel_3 = uilabel(app.FoodGrid);
            app.FederalLabel_3.Text = 'Groceries';
            app.Groceries = uieditfield(app.FoodGrid, 'numeric');
            app.Groceries.ValueChangedFcn = createCallbackFcn(app, @GroceriesValueChanged, true);
            app.Groceries.Value = 800;
            app.StateLabel_3 = uilabel(app.FoodGrid);
            app.StateLabel_3.Text = 'Take Out';
            app.TakeOut = uieditfield(app.FoodGrid, 'numeric');
            app.TakeOut.ValueChangedFcn = createCallbackFcn(app, @TakeOutValueChanged, true);
            app.TakeOut.Value = 100;
            app.SSLabel_3 = uilabel(app.FoodGrid);
            app.SSLabel_3.Text = 'Dine In';
            app.DineIn = uieditfield(app.FoodGrid, 'numeric');
            app.DineIn.ValueChangedFcn = createCallbackFcn(app, @DineInValueChanged, true);
            app.DineIn.Value = 150;
            app.UtilitiesmonthPanel = uipanel(app.ExpensesGrid);
            app.UtilitiesmonthPanel.Title = 'Utilities ($/month)';
            app.UtilitiesGrid = uigridlayout(app.UtilitiesmonthPanel);
            app.FederalLabel_4 = uilabel(app.UtilitiesGrid);
            app.FederalLabel_4.Text = 'Electric';
            app.Electric = uieditfield(app.UtilitiesGrid, 'numeric');
            app.Electric.ValueChangedFcn = createCallbackFcn(app, @ElectricValueChanged, true);
            app.Electric.Value = 100;
            app.StateLabel_4 = uilabel(app.UtilitiesGrid);
            app.StateLabel_4.Text = 'Water/Sewer';
            app.WaterSewage = uieditfield(app.UtilitiesGrid, 'numeric');
            app.WaterSewage.ValueChangedFcn = createCallbackFcn(app, @WaterSewageValueChanged, true);
            app.WaterSewage.Value = 40;
            app.SSLabel_6 = uilabel(app.UtilitiesGrid);
            app.SSLabel_6.Text = 'Internet';
            app.Internet = uieditfield(app.UtilitiesGrid, 'numeric');
            app.Internet.ValueChangedFcn = createCallbackFcn(app, @InternetValueChanged, true);
            app.Internet.Value = 40;
            app.SSLabel_7 = uilabel(app.UtilitiesGrid);
            app.SSLabel_7.Text = 'Oil';
            app.Gas = uieditfield(app.UtilitiesGrid, 'numeric');
            app.Gas.ValueChangedFcn = createCallbackFcn(app, @GasValueChanged, true);
            app.Gas.Value = 100;
            app.SSLabel_5 = uilabel(app.UtilitiesGrid);
            app.SSLabel_5.Text = 'Trash';
            app.Trash = uieditfield(app.UtilitiesGrid, 'numeric');
            app.Trash.ValueChangedFcn = createCallbackFcn(app, @TrashValueChanged, true);
            app.Trash.Value = 37;
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.GridLayout = uigridlayout(app.HistoryTab);
            app.YearDropDownLabel = uilabel(app.GridLayout);
            app.YearDropDownLabel.Text = 'Year';
            app.Year = uidropdown(app.GridLayout);
            app.Year.Items = {'2021', '2022', '2023', '2024', '2025', '2026', '2027', '2028', '2029', '2030'};
            app.Year.ValueChangedFcn = createCallbackFcn(app, @YearValueChanged, true);
            app.Year.Value = '2021';
            app.MonthDropDownLabel = uilabel(app.GridLayout);
            app.MonthDropDownLabel.Text = 'Month';
            app.Month = uidropdown(app.GridLayout);
            app.Month.Items = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.Month.ItemsData = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12'};
            app.Month.ValueChangedFcn = createCallbackFcn(app, @MonthValueChanged, true);
            app.Month.Value = '03';
            app.TypeLabel = uilabel(app.GridLayout);
            app.TypeLabel.Text = 'Type';
            app.Type = uidropdown(app.GridLayout);
            app.Type.Items = {'Electric', 'Gas', 'Bank', 'ETrade', '401k', 'Capital One', 'HSA'};
            app.Type.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @TypeValueChanged, true);
            app.Type.Value = '3';
            app.PDF = uihyperlink(app.GridLayout);
            app.PDF.HyperlinkClickedFcn = createCallbackFcn(app, @PDFHyperlinkClicked, true);
            app.PDF.Text = 'PDF';
            app.HistoryTabGroup = uitabgroup(app.GridLayout);
            app.ElectricTab = uitab(app.HistoryTabGroup);
            app.ElectricTab.Title = 'Electric';
            app.BankGrid_2 = uigridlayout(app.ElectricTab);
            app.ElectricPlot = uiaxes(app.BankGrid_2);
            title(app.ElectricPlot, 'kWh Usage')
            ylabel(app.ElectricPlot, 'kWh')
            app.ElectricBalance = uilabel(app.BankGrid_2);
            app.ElectricBalance.Text = '0';
            app.ElectricUsage = uilabel(app.BankGrid_2);
            app.ElectricUsage.Text = '0';
            app.ElectricDelivery = uilabel(app.BankGrid_2);
            app.ElectricDelivery.Text = '0';
            app.ElectricSupply = uilabel(app.BankGrid_2);
            app.ElectricSupply.Text = '0';
            app.SupplyChargeLabel = uilabel(app.BankGrid_2);
            app.SupplyChargeLabel.Text = 'Supply Charge';
            app.DeliveryChargeLabel = uilabel(app.BankGrid_2);
            app.DeliveryChargeLabel.Text = 'Delivery Charge';
            app.EnergyUsageLabel = uilabel(app.BankGrid_2);
            app.EnergyUsageLabel.Text = 'Energy Usage';
            app.BalanceLabel = uilabel(app.BankGrid_2);
            app.BalanceLabel.Text = 'Balance';
            app.GasTab = uitab(app.HistoryTabGroup);
            app.GasTab.Title = 'Gas';
            app.BankTab = uitab(app.HistoryTabGroup);
            app.BankTab.Title = 'Bank';
            app.BankGrid = uigridlayout(app.BankTab);
            app.BankPlot = uiaxes(app.BankGrid);
            title(app.BankPlot, 'Balance')
            ylabel(app.BankPlot, '$/1000')
            app.BankBeg = uilabel(app.BankGrid);
            app.BankBeg.Text = '0';
            app.BankDep = uilabel(app.BankGrid);
            app.BankDep.Text = '0';
            app.BankATM = uilabel(app.BankGrid);
            app.BankATM.Text = '0';
            app.BankOther = uilabel(app.BankGrid);
            app.BankOther.Text = '0';
            app.BankChecks = uilabel(app.BankGrid);
            app.BankChecks.Text = '0';
            app.BankService = uilabel(app.BankGrid);
            app.BankService.Text = '0';
            app.BankEnding = uilabel(app.BankGrid);
            app.BankEnding.Text = '0';
            app.EndingBalanceLabel = uilabel(app.BankGrid);
            app.EndingBalanceLabel.Text = 'Ending Balance';
            app.ServiceFeesLabel = uilabel(app.BankGrid);
            app.ServiceFeesLabel.Text = 'Service Fees';
            app.ChecksLabel = uilabel(app.BankGrid);
            app.ChecksLabel.Text = 'Checks';
            app.OtherSubtractionsLabel = uilabel(app.BankGrid);
            app.OtherSubtractionsLabel.Text = 'Other Subtractions';
            app.ATMDebitSubLabel = uilabel(app.BankGrid);
            app.ATMDebitSubLabel.Text = 'ATM / Debit Sub.';
            app.DepositsAdditionsLabel = uilabel(app.BankGrid);
            app.DepositsAdditionsLabel.Text = 'Deposits / Additions';
            app.BeginningBalanceLabel = uilabel(app.BankGrid);
            app.BeginningBalanceLabel.Text = 'Beginning Balance';
            app.DeltaLabel = uilabel(app.BankGrid);
            app.DeltaLabel.Text = 'Delta';
            app.BankDelta = uilabel(app.BankGrid);
            app.BankDelta.Text = '0';
            app.ETradeTab = uitab(app.HistoryTabGroup);
            app.ETradeTab.Title = 'ETrade';
            app.BankTab_2 = uitab(app.HistoryTabGroup);
            app.BankTab_2.Title = 'Bank';
            app.BankGrid_3 = uigridlayout(app.BankTab_2);
            app.BankPlot_2 = uiaxes(app.BankGrid_3);
            title(app.BankPlot_2, 'Balance')
            ylabel(app.BankPlot_2, '$/1000')
            app.BankBeg_2 = uilabel(app.BankGrid_3);
            app.BankBeg_2.Text = '0';
            app.BankDep_2 = uilabel(app.BankGrid_3);
            app.BankDep_2.Text = '0';
            app.EndingBalanceLabel_2 = uilabel(app.BankGrid_3);
            app.EndingBalanceLabel_2.Text = 'Ending Balance';
            app.BeginningBalanceLabel_2 = uilabel(app.BankGrid_3);
            app.BeginningBalanceLabel_2.Text = 'Beginning Balance';
            app.NetChangeLabel = uilabel(app.BankGrid_3);
            app.NetChangeLabel.Text = 'Net Change';
            app.BankDelta_2 = uilabel(app.BankGrid_3);
            app.BankDelta_2.Text = '0';
            app.FidelityTab = uitab(app.HistoryTabGroup);
            app.FidelityTab.Title = 'Fidelity';
            app.CapitalOneTab = uitab(app.HistoryTabGroup);
            app.CapitalOneTab.Title = 'Capital One';
            app.HSATab = uitab(app.HistoryTabGroup);
            app.HSATab.Title = 'HSA';
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            % Show the figure after all components are created
            app.BudgetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Budget

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BudgetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BudgetUIFigure)
        end
    end
end